classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ConventionISO21920Panel        matlab.ui.container.Panel
        mLabel                         matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        FeatureCharacterizationEditField  matlab.ui.control.EditField
        FeatureparameterPanel          matlab.ui.container.Panel
        CheckBox_2                     matlab.ui.control.CheckBox
        AttributetypeDropDown          matlab.ui.control.DropDown
        AttributetypeDropDownLabel     matlab.ui.control.Label
        StatisticsvalueEditField       matlab.ui.control.NumericEditField
        StatisticsvalueEditFieldLabel  matlab.ui.control.Label
        NIsigEditField                 matlab.ui.control.NumericEditField
        NIsigEditFieldLabel            matlab.ui.control.Label
        AttributestatisticsDropDown    matlab.ui.control.DropDown
        AttributestatisticsDropDownLabel  matlab.ui.control.Label
        SignificantfeaturesDropDown    matlab.ui.control.DropDown
        SignificantfeaturesDropDownLabel  matlab.ui.control.Label
        LoadprofileButton              matlab.ui.control.Button
        ThresholdEditField             matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel        matlab.ui.control.Label
        FeaturetypeDropDown            matlab.ui.control.DropDown
        FeaturetypeDropDownLabel       matlab.ui.control.Label
        PruningTypeDropDown            matlab.ui.control.DropDown
        PruningTypeDropDownLabel       matlab.ui.control.Label
        WatershedsegmentationPanel     matlab.ui.container.Panel
        CheckBox                       matlab.ui.control.CheckBox
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        x
        dx
        z
    end
    
    properties (Access = public)
        
    end
    
    methods (Access = private)
        function update_FC(app)
            FT = app.FeaturetypeDropDown.Value;
            PT = app.PruningTypeDropDown.Value;
            TH_perc = app.CheckBox.Value;
            if PT == "None"
                TH ="";
            elseif TH_perc == 0
                TH = " " + num2str(app.ThresholdEditField.Value);
            else
                TH = " " + num2str(app.ThresholdEditField.Value) + " %";
            end
            Fsig = app.SignificantfeaturesDropDown.Value;
            NIsig_perc = app.CheckBox_2.Value;
            if Fsig == "All"
                NIsig ="";
            elseif NIsig_perc == 0
                NIsig = " " + num2str(app.NIsigEditField.Value);
            else
                NIsig = " " + num2str(app.NIsigEditField.Value) + " %"; 
            end
            AT = app.AttributetypeDropDown.Value;
            Astats = app.AttributestatisticsDropDown.Value;
            if Astats == "Perc" || Astats == "Hist"
                vstats =" " + num2str(app.StatisticsvalueEditField.Value);
            else
                vstats = "";
            end
%             app.FeatureCharacterizationEditField.Value = strcat("FC; ", FT, "; ",PT, TH,"; ",Fsig, NIsig,"; ",AT,"; ",Astats," ",vstats);
            app.FeatureCharacterizationEditField.Value = strcat('FC; ', FT, '; ',PT, TH,'; ',Fsig, NIsig,'; ',AT,'; ',Astats,' ',vstats);
        end

        function update_plot(app)
            cla(app.UIAxes) 
%             FC_complete = app.FeatureCharacterizationEditField.Value;
%             FC = split(FC_complete,"; ")
            FC_complete = convertCharsToStrings(app.FeatureCharacterizationEditField.Value);
            FC = split(FC_complete,";");

            [xFC, M, meta]= feature_characterization_gui(app,app.z, app.dx, FC(2), FC(3), FC(4), FC(5), FC(6));
            hold(app.UIAxes,'on')
            plot_motifs_gui(app,app.UIAxes, app.z, app.dx, M, meta.Fsig, meta.NIsig)
            app.Label.Text = num2str(xFC,2);
            switch meta.AT
                case {"HDh","HDw","HDl","PVh"}
                    app.mLabel.Text = "µm";
                case "HDv"
                    app.mLabel.Text = "ml/m²";
                case "Count"
                    app.mLabel.Text = "";
                case "Curvature"
                    app.mLabel.Text = "1/µm";
            end
        end
        %% Plot Motifs
        function plot_motifs_gui(app, ax, z, dx, M, Fsig, NIsig)
        %% preallocating
        iend = length(z);
        x = (0:dx:iend*dx - dx)'/1000;
        z = z(:);                                              % make sure that vector is colum-vector
        FT = sign(z(floor(M(1).ilp)) - z(floor(M(1).iv)));     % identify feature type. FT = -1 if Hills, FT = 1 if Dales
        %% plot settings
        % xlabel('profile length / \textit{mm}','Interpreter','latex','FontSize',10)
        % ylabel('profile height / \textit{$\mu m$}','Interpreter','latex','FontSize',10)
        linewidth=0.85;
        alpha = 0.5;                                           % transparicy-value for not significant motifs
        col = [0 0.4470 0.7410];                               % color of motifs
        ax.XLim = [0 max(x)];
        ax.YLim= 1.1*[min(z) max(z)];
        ax.XGrid = 'on';
        ax.YGrid = 'on';
        ax.Box = 'on';
        
        %% fill motifs and motif "frames"
        for k = 1:length(M)
            p{k} = patch_featureelement_gui(app, ax, z, dx, M(k), col);
        end
        
        for k = 1:length(M)
            pm{k} = plot(ax, (dx*[(M(k).ilp) (M(k).ilp) (M(k).ihp) (M(k).ihp)]-dx)/1000,...
                [z(floor(M(k).ilp)) z(floor(M(k).iv)) z(floor(M(k).iv)) z(floor(M(k).ihp))],...
                'color', [1 0 0],'LineStyle','-');
        end
        
        %% plot
        plot(ax, x, z, 'k', 'LineWidth', linewidth)
        
        %% not significant features
        for n = find([M.sig]==0)
            for i = 1:length(p{1,n})             
                p{1,n}{i}.FaceAlpha = alpha;
                pm{n}.LineStyle =":";
            end
        end
        if nargin > 4
            switch Fsig
                case "Open"
                    yline(ax, NIsig, '--', 'LineWidth', 1);
                case "Closed"
                    yline(ax, NIsig, '--', 'LineWidth', 1); 
                case "Top"
            end
        end
        function p = patch_featureelement_gui(app,ax, z, dx, Mr, col)
        d = sign(Mr.ihp - Mr.ilp);
        ihi = [Mr.ilp; Mr.ihi];
        zlp = z(floor(Mr.ilp));
        for i=1:2:length(ihi)-1
            if d == 1
                i1 = ceil(ihi(i));
                i2 = floor(ihi(i+1));
            else
                i1 = floor(ihi(i));
                i2 = ceil(ihi(i+1));
            end
            xf = dx*([ihi(i); (i1:d:i2)'; ihi(i+1)] - 1)/1000;
            zf = [zlp; z(i1:d:i2); zlp];
            p{ceil(i/2)} = patch(ax, [xf; flip(xf)], [zf; ones(length(zf), 1)*zlp], col, 'LineStyle', 'none');
        end
        end
        end
        %% feature_paramter
        function [xFC, M, ATTR, Fsig, NIsig] = feature_parameter_gui(app, z, dx, M, Fsig, NIsig, AT, Astats, xstats)
        %% step 4: determine significant_features
        I_Nsig = [];
        switch Fsig
            case {"Open", "Closed"}
                % feature type indicator (FTI=-1 if hills/peaks, FTI=1 if dales/pits)
                FTI = sign(z(floor(M(1).ilp)) - z(floor(M(1).iv)));
                % determine z-values of low-peaks and pits
                zlp = z(floor([M.ilp]));
                % determine indices of not significant features
                if Fsig == "Open"
                    I_Nsig = find(FTI*zlp > FTI*NIsig);
                else
                    zv = z(floor([M.iv]));
                    I_Nsig = find(FTI*zlp < FTI*NIsig | FTI*zv > FTI*NIsig);
                end
            case {"Top", "Bot"}
                % determine attribute values
                ATTR = feature_attribute_gui(app, z, dx, M, "PVh");
                % determine indices (I) of sorted zv-values in zv
                [~, I_sort] = sort(ATTR, 'descend');
                % if NIsig is higher than nM use nM
                NIsig = min(NIsig, length(M));
                I_Nsig = I_sort(NIsig+1:end);
        end
        % set indicator M.sig zero for not significant motifs
        for i = 1:length(I_Nsig)
            M(I_Nsig(i)).sig = 0;
        end
        
        %% step 5: determine attibrute-values of significant features
        ATTR = feature_attribute_gui(app, z, dx, M, AT);
        
        %% step 6: attribute statistics
        switch Astats
            case "Mean"
                xFC = mean(ATTR);
            case "Max"
                xFC = max(ATTR);
            case "Min"
                xFC = min(ATTR);
            case "StdDev"
                xFC = std(ATTR);
            case "Perc"
                xFC = sum(ATTR > xstats)/nMsig;
            case "Hist"
                xFC = histogram(ATTR, xstats);
            case "Sum"
                xFC = sum(ATTR);
            case "Density"
                xFC = ATTR/(dx*(length(z)-1));
            case "Median"
                xFC = median(ATTR);
            case "Span"
                xFC = max(ATTR) - min(ATTR);
            case "RMS"  
                xFC = rms(ATTR);
        end
        end
        %% feature_characterization
        function [xFC, M, meta] = feature_characterization_gui(app, z, dx, FT, pruning, significant, AT, stats)
        % INPUTS:
        %   z           - vertical profile values
        %   dx          - step size in x-direction
        %   FT          - 'D', 'V', 'H', 'P'
        %   pruning     - 'None', 'Wolfprune TH/X%', 'Width TH/X%', 'VolS TH', 'DevLength TH'
        %                 Threshold TH in units of corresponding attribute.
        %                 For Wolfprune or Width, the threshold value can also be 
        %                 specified as a percentage. In this case, X% of Rz is used
        %                 as the threshold in the case of Wolfprune and X% of Le in
        %                 the case of Width.
        %   significant - 'All', 'Closed c', 'Open c', 'Bot N', 'Top N'
        %                 c can be an absolute value in µm or if the value is given 
        %                 as a percentage it is interpreted as a material ratio 
        %                 from which the height is then determined.
        %                 N specifies the number of top or bot values.
        %   AT          - 'HDh', 'HDv', 'HDw', 'HDl', 'PVh', 'Curvature', 'Count'
        %   stats       - 'Mean', 'Max', 'Min', 'StdDev', 'Perc X', 'Hist X', 'Sum', 'Density'
        %                 for "Hist", x specifies the number of bins in the histogram. 
        %                 for "Perc", x specifies the threshold in the units of the
        %                 corresponding attribute
        % OUTPUTS:
        %   xFC         - parameter based on feature characterization
        %   M           - structured array of motifs
        %   meta        - meta data for further processing (e.g. plotting)
        
        % parse pruning
        str = split(pruning, " ");
        PT = str(1);
        TH = [];
        if numel(str) >= 2
            TH = str2double(str(2));
            if isnan(TH)
                TH = str(2);
            end
        end
        if numel(str) == 3
            TH = (TH/100)*iso21920('fnciso21920_feature_parameters_peak_pit',z, length(z)/5 ,5).xz;
        end
        
        % parse significant
        str = split(significant, " ");
        Fsig = str(1);
        NIsig = str2double(str(min(end,2)));
        if numel(str) == 3
            [hintersection, material] = iso21920('fnciso21920_material_ratio_functions_imp',z,length(z));
            NIsig = max(z) + iso21920('fnciso21920_material_ratio_functions_xcm', material, hintersection,length(material), NIsig, 1);
        end
        
        % parse stats
        str = split(stats, " ");
        Astats = str(1);
        vstats = str2double(str(end));
        
        M = watershed_segmentation_gui(app, z, dx, FT, PT, TH);
        [xFC, M, ATTR]  = feature_parameter_gui(app, z, dx, M, Fsig, NIsig, AT, Astats, vstats);
        meta = struct('ATTR', ATTR, 'nM', length(M), 'PT', PT, 'TH', TH, 'Fsig', Fsig, 'NIsig', NIsig,'AT', AT, 'Astats', Astats,'vstats', vstats);
        end
        %% watershed_segmentation
        function M = watershed_segmentation_gui(app,z, dx, FT, PT, TH)
        % INPUTS:
        %  z  - vertical profile values
        %  dx - step size in x-direction
        %  FT - feature type: {'D', 'V', 'H', 'P'}
        %  PT - pruning type: {'None', 'Wolfprune', 'Width', 'VolS', 'DevLength'}
        %  TH - threshold for pruning (not needed if PT = 'None')
        % OUTPUTS:
        %  M    - structure array with motifs with four members 
        %         (referring to Dale-motif):
        %         M.iv    - (interpolated) index of pit
        %         M.ilp   - (interpolated) index of low-peak
        %         M.ihp   - (interpolated) index of high-peak
        %         M.ihi   - (interpolated) index of heightintersection
        %         M.sig   - indicator for significant features
        % meta  - stucture array with meta information
        
        %% step 1: determine indices of all peaks and pits
        % invert z-values if hill-motifs are searched. Allows the rest of the code
        % refers to dale-motifs
        if FT == 'H' || FT == 'P'
            z = -z;
        end
        % consider only the first of any adjacent pairs of equal values so that
        % plateaus are single points
        iNeq = [1; 1 + find(z(1:end-1) ~= z(2:end))];
        % determine the slope for each point. s=-1 neg. slope; s=1 pos. slope
        s = sign(diff(z(iNeq)));
        % change from 1 to -1 corresponds to a peak. -1 to 1 to pit
        ipNeq = 1 + find(diff(s) == -2);
        ivNeq = 1 + find(diff(s) == 2);
        % peaks and pits indices into the original index vector
        ipv = [iNeq(ipNeq); iNeq(ivNeq)];
        % examine peaks and pits that are plateaus
        for j = find(z(ipv) == z(ipv+1))'
            % number of equal high values on the plateau
            n_plateau = find(z(ipv(j):end) ~= z(ipv(j)), 1) - 1;
            % replace index with (interpolated) index of middle of plateau
            ipv(j) = ipv(j) + (n_plateau - 1)/2;                      
        end
        % (interpolated) indices of peaks and pits (plateaus taken into account)
        ip = ipv(1:length(ipNeq));
        iv = ipv(length(ipNeq) + 1:end);
        
        %% step 2: determine motifs
        % just keep indices of pits that are enclosed with peaks
        iv = iv(iv > ip(1) & iv < ip(end));
        % enrich structure array M with information for each motif such as pit
        % (iv), low-peak (lp) and high-peak (hp) and the height intersection (ihi)
        nM = length(iv);
        for k = 1:nM
            [ilp, ihp] = get_ilp_ihp_gui(app,z, [ip(k), ip(k+1)]);
            ihi = height_intersections_gui(app,z, ilp, ihp);
            M(k) = struct('iv', iv(k), 'ilp', ilp, 'ihp', ihp, 'ihi', ihi,'sig',1);
        end
        
        %% step 3: pruning (see pruning cases in readme.md)
        % skip pruning if pruning type (PT) is "None"
        if PT ~= "None"
            % determine attribute-values of each motif
            ATTR = feature_attribute_gui(app, z, dx, M, PT);
            % find optimal limit for maximum periodicity (if requested)
            if strcmp(TH, "opt")
               TH = optimal_periodicity_gui(app,z, dx, M, nM, ATTR, PT);
            end
            % prune aslong minimal attribute value is lower than given threshold
            while min(ATTR) < TH
                [M, nM, ATTR] = prune_min_motif_gui(app,z, dx, M, nM, ATTR, PT);
            end
        end
        %% height intersection function
        function ihi = height_intersections_gui(app,z, ilp, ihp)
        % INPUTS:
        %   z    - vertical profile values
        %   ilp  - (interpolated) index of low-peak
        %   ihp  - (interpolated) index of high-peak
        % OUTPUTS:
        %   ihi  - (interpolated) index of height intersection
        
        % direction in which to search ihi outgoing form low-peak
        dir = sign(ihp - ilp);
        j = round(ilp);
        zlp = z(j);
        ihi = [];
        % getting height-intesections (based on crossing-the-line-segmentation)
        while j ~= round(ihp)
            if (z(j) < zlp && z(j+dir) >= zlp) || (z(j) > zlp && z(j+dir) <= zlp)
                ihi = [ihi; j + dir*(zlp - z(j))/(z(j + dir) - z(j))];
            end
            j=j+dir;
        end
        end
        
        %% determine indices of low-peak and high-peak based of 2 given indices
        function [ilp, ihp] = get_ilp_ihp_gui(app,z, ip_surr)
        % INPUTS:
        %   z       - vertical profile values
        %   ip_surr - (interpolated) indices of the two surrounding peaks of examined pit
        % OUTPUTS:
        %   ilp     - (interpolated) index of low-peak
        %   ihp     - (interpolated) index of high-peak
        [~, I] = min([z(floor(ip_surr(1))), z(floor(ip_surr(2)))]);
        ilp  = ip_surr(I);
        ihp = ip_surr(3 - I);
        end
        
        
        %% reduced find function (for translation to other languages)
        function iN0 = find_gui(app,A, firstOnly) 
        % INPUTS
        %   A         - Array 
        %   firstOnly - optional: if set to 1 only first index of nonzeroelement is
        %               returned. if set to 0 or not provided all indices of 
        %               nonzeroelements are returned
        % OUTPUTS
        %   iN0       - indices of nonzero entries in A
        if nargin < 2
            firstOnly = 0;
        end
        iN0 = [];
        for i = 1:length(A)
            if A(i) ~= 0
                iN0 = [iN0; i];
                if firstOnly == 1
                    return
                end
            end
        end
        end
        %% optimal threshold function
        function TH = optimal_periodicity_gui(app,z, dx, M, nM, ATTR, PT)
        % minimal Q-Value
        Qmin = 3;
        % set default threshold for the case that Qmin is never exceeded
        TH = 100;
        % prune until just two motifs are left
        while nM > 2
            % parameter Q as a measure for periodicity
            Q = mean(ATTR) / std(ATTR);
            % if Q is greater than Qmin then overwrite Qmin with the current 
            % Q-value and TH with minimal attribute value
            if Q > Qmin
                Qmin = Q;
                TH = min(ATTR);
            end
            [M, nM, ATTR] = prune_min_motif_gui(app,z, dx, M, nM, ATTR, PT);
        end
        end
        %% prune min-motif function
        function [M, nM, ATTR] = prune_min_motif_gui(app,z, dx, M, nM, ATTR, PT)
        % row-index of minimal attribute value
        [~, rmin] = min(ATTR);
        % save motif with minimal attribute-value temporarily, delete 
        % corresponding entry in motif-array and attribute-vector. update nM
        Mmin = M(rmin);
        M(rmin) = []; ATTR(rmin) = []; 
        nM = nM - 1;
        % determine row-index of motif which is to update (rU). dir=-1: left of
        % min-motif. dir=1 right of min-motif. rU = rmin if dir=1 because
        % min-motif was deleted in motif array. else rU = rmin - 1.
        dir = sign(Mmin.ilp - Mmin.iv);
        rU = rmin - (dir == -1);
        % case 1: if in that direction is border no further steps are required
        if rU == 0 || rU > nM
            return
        end
        % case 2: if low-peak of min-motif and motif to update is the same then 
        % determine low-peak and high-peak of motif to update. Update height
        % intersection and attribute-value (see behind if-query)
        if M(rU).ilp == Mmin.ilp
        [M(rU).ilp, M(rU).ihp] = get_ilp_ihp_gui(app,z, [M(rU).ihp, Mmin.ihp]);
        % case 3: low-peak of min-motif is equal the high-peak of motif to
        % update. replace high-peak of motif to merge with high-peak of
        % min-motif
        else
            M(rU).ihp = Mmin.ihp;
            % case 3.1: if low-peak of motif to merge is lower or equal than
            % pit of min-motif no further steps are required
            if z(floor(M(rU).ilp)) <= z(floor(Mmin.iv))
                return
            end
        % case 3.2: low-peak of motif to merge is higher than pit of
        % min-motif: refresh height intersection and attribute-value
        % (see behind if-query)
        end
        % update height intersection and attribute-value of motif to update for
        % case 2 and 3.2
        M(rU).ihi = height_intersections_gui(app,z, M(rU).ilp, M(rU).ihp);
        ATTR(rU) = feature_attribute_gui...
            (app, z, dx, M(rU), PT);
        end
        end
        %% feature_attribute
        function ATTR = feature_attribute_gui(app,z, dx, M, AT)
        % INPUTS:
        %   z   - vertical profile values
        %   dx  - step size in x-direction
        %   M   - motif array
        %   AT  - attribute type {"Wolfprune", "HDh", "Width", "HDw", "VolS",...
        %                         "HDv", "DevLength", "HDl", "PVh", "Curvature",...
        %                         "Count"}
        % Outputs
        %   ATTR - attribute value of given motifs
        
        I_sig = find([M.sig] == 1);
        switch AT
            case {"Wolfprune", "HDh"}
                ATTR = abs(z(floor([M(I_sig).ilp])) - z(floor([M(I_sig).iv])));
            case {"Width", "HDw"}
                for i = 1:length(I_sig)
                    ATTR(i) = dx*max(abs(M(I_sig(i)).ihi - M(I_sig(i)).ilp));
                end
            case {"VolS", "HDv"}
                for i = 1:length(I_sig)
                    ATTR(i) = HDvf_gui(app,z, dx, M(I_sig(i)));
                end
            case {"DevLength", "HDl"}
                for i = 1:length(I_sig)
                    ATTR(i) = HDlf_gui(app,z, dx, M(I_sig(i)));
                end
            case "PVh"
                FTI = sign(z(floor(M(1).ilp)) - z(floor(M(1).iv)));
                ATTR = -FTI*z(floor([M(I_sig).iv]));
            case "Curvature"
                for i = 1:length(I_sig)
                    ATTR(i) = curvature_gui(app,z, dx, M(I_sig(i)).iv);
                end
            case "Count"
                ATTR = ones(1, length(I_sig));
        end
        function HDv = HDvf_gui(app,z, dx, Mr)
        % all heightintersections incl. low-peak
        ihi = [Mr.ilp; Mr.ihi];
        zlp = z(floor(Mr.ilp));
        HDv = 0;
        % determine direction 
        d = sign(Mr.ihp - Mr.ilp);
        for i=1:2:length(ihi)-1
            if d == 1
                i1 = ceil(ihi(i));
                i2 = floor(ihi(i+1));
            else
                i1 = floor(ihi(i));
                i2 = ceil(ihi(i+1));
            end
            xf = dx*([ihi(i); (i1:d:i2)'; ihi(i+1)] - 1);
            zf = [zlp; z(i1:d:i2); zlp];
            HDv = HDv + abs(trapz(xf,zf-zlp));
        end
        HDv = HDv/(length(z)*dx);
        end

        function  HDl = HDlf_gui(app,z, dx, Mr)
        d = sign(Mr.iv - Mr.ilp);
        zlp = z(floor(Mr.ilp));
        [~, I] = max(abs(Mr.ilp - Mr.ihi));
        ihi = Mr.ihi(I);
        if d == 1
            i1 = ceil(Mr.ilp);
            i2 = floor(ihi);
        else
            i1 = floor(Mr.ilp);
            i2 = ceil(ihi);
        end
        zf = [z(i1:d:i2)];
        HDl = sum(sqrt(1 + (diff(zf)./dx).^2))*dx + mod(i1, 1)*dx;
        
        if mod(ihi, 1) ~= 0
            rhi = (d == 1)*mod(ihi, 1) + (d == -1)*(1 - mod(ihi,1));
            HDl = HDl + sqrt(rhi^2+(zlp-z(i2))^2);
        end
        end
        
        function cx = curvature_gui(app,z, dx, ix)
        ix=floor(ix);
        for n = 1:length(ix)
            i = ix(n);
            switch i
                case 1
                dz1 = (-147*z(1) + 360*z(2) - 450*z(3) + 400*z(4) - 225*z(5) + ...
                    72*z(6) - 10*z(7))/(60*dx);
                dz2 = (812*z(1) - 3132*z(2) + 5265*z(3) - 5080*z(4) + 2970*z(5) ...
                    - 972*z(6) + 137*z(7))/(180*(dx)^2);
                case 2
                dz1 = (-10*z(1) - 77*z(2) + 150*z(3) - 100*z(4) + 50*z(5) - ...
                    15*z(6) + 2*z(7))/(60*dx);
                dz2 = (137*z(1) - 147*z(2) - 255*z(3) + 470*z(4) - 285*z(5) + ...
                    93*z(6) - 13*z(7))/(180*(dx)^2);
                case 3
                dz1 = (2*z(1) - 24*z(2) - 35*z(3) + 80*z(4) - 30*z(5) + 8*z(6) ...
                    - 1*z(7))/(60*dx);
                dz2 = (-13*z(1) + 288*z(2) - 420*z(3) + 200*z(4) + 15*z(5) + ...
                    12*z(6) + 2*z(7))/(180*(dx)^2);
                case length(z) - 3
                dz1 = (z(end-7) - 8*z(end-6)+30*z(end-5) - 80*z(end-4) + ...
                    35*z(end-3) + 24*z(end-2) - 2*z(end-1))/(60*dx);
                dz2 = (2*z(end-7) - 12*z(end-6) + 15*z(end-5) + 200*z(end-4) - ...
                    420*z(end-3) + 228*z(end-2) - 13*z(end-1))/(180*(dx)^2);
                case length(z) - 2
                dz1 = (-2*z(end-7) + 15*z(end-6) - 50*z(end-5) + 100*z(end-4) - ...
                    150*z(end-3) + 77*z(end-2) + 10*z(end-1))/(60*dx);
                dz2 = (-13*z(end-7) + 93*z(end-6) - 285*z(end-5) + 470*z(end-4) ...
                    - 255*z(end-3) - 147*z(end-2) + 137*z(end-1))/(180*(dx)^2);
                case length(z)-1
                dz1 = (10*z(end-7) - 72*z(end-6) + 225*z(end-5) - 400*z(end-4) ...
                    + 450*z(end-3) - 360*z(end-2) + 147*z(end-1))/(60*dx);
                dz2 = (137*z(end-7) + 93*z(end-6) - 285*z(end-5) + 470*z(end-4) ...
                    - 255*z(end-3) - 147*z(end-2) + 137*z(end-1))/(180*(dx)^2);
                otherwise
                dz1 = (-z(i-3) + 9*z(i-2) - 45*z(i-1) + 45*z(i+1) - 9*z(i+2) + ...
                    z(i+3))/(60*dx);
                dz2 = (2*z(i-3) - 27*z(i-2) + 270*z(i-1) - 490*z(i) + 270*z(i+1)...
                    - 27*z(i+2) + 2*z(i+3))/(180*(dx)^2);
            end
            cx(n) = dz2/(1 + dz1^2)^(3/2);
        end
        end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.NIsigEditField.Enable = 'off';
            app.StatisticsvalueEditField.Enable = 'off';
            update_FC(app)
        end

        % Value changed function: FeaturetypeDropDown
        function FeaturetypeDropDownValueChanged(app, event)
            update_FC(app);
            update_plot(app);
        end

        % Button pushed function: LoadprofileButton
        function LoadprofileButtonPushed(app, event)
            [filename, path] = uigetfile;
            profile = load(strcat(path,filename));
            app.z = profile.z;
            if ~exist('profile.x','var')
                app.dx=0.5;
                app.x = app.dx*(0:1:length(app.z) - 1)'/1000;
            end
            update_plot(app)
        end

        % Value changed function: PruningTypeDropDown
        function PruningTypeDropDownValueChanged(app, event)
            PT = convertCharsToStrings(app.PruningTypeDropDown.Value);
            if PT == "None"
                app.ThresholdEditField.Enable = 'off';
            else
                app.ThresholdEditField.Enable = 'on';
            end
            update_FC(app);
            update_plot(app)
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            update_FC(app);
            update_plot(app)
        end

        % Value changed function: FeatureCharacterizationEditField
        function FeatureCharacterizationEditFieldValueChanged(app, event)
            FC_complete = convertCharsToStrings(app.FeatureCharacterizationEditField.Value);
            FC = split(FC_complete,"; ");
            [xFC, M, meta] = feature_characterization_gui(app, app.z,app.dx,FC(2),FC(3),FC(4),FC(5),FC(6));
            app.PruningTypeDropDown.Value = meta.PT;
            app.ThresholdEditField.Value = meta.TH;
            app.SignificantfeaturesDropDown.Value = meta.Fsig;
            if meta.Fsig ~= "All"
                app.NIsigEditField.Enable = 'on';
                app.NIsigEditField.Value = meta.NIsig;
            else
                app.NIsigEditField.Enable = 'off';
            end
            app.AttributestatisticsDropDown.Value = meta.Astats;
            if ~isnan(meta.vstats)
                app.StatisticsvalueEditField.Value = meta.vstats;
                app.StatisticsvalueEditField.Enable ='on';
            else
                app.StatisticsvalueEditField.Enable ='off';
            end
            update_plot(app)
        end

        % Value changed function: SignificantfeaturesDropDown
        function SignificantfeaturesDropDownValueChanged(app, event)
            Fsig = convertCharsToStrings(app.SignificantfeaturesDropDown.Value);
            if Fsig == "All"
                app.NIsigEditField.Enable = 'off';
            else
                app.NIsigEditField.Enable = 'on';
            end
            update_FC(app)
            update_plot(app)
        end

        % Value changed function: AttributestatisticsDropDown
        function AttributestatisticsDropDownValueChanged(app, event)
            Astats = convertCharsToStrings(app.AttributestatisticsDropDown.Value);
            if Astats == "Perc" || Astats == "Hist"
                app.StatisticsvalueEditField.Enable = 'on';
            else
                app.StatisticsvalueEditField.Enable = 'off';
            end
            update_FC(app)
        end

        % Value changed function: NIsigEditField
        function NIsigEditFieldValueChanged(app, event)
            value = app.NIsigEditField.Value;
            update_FC(app)
            update_plot(app)
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            update_FC(app)
            update_plot(app)
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
%             TH_perc = app.CheckBox_2.Value;
            update_FC(app)
            update_plot(app)
        end

        % Value changed function: AttributetypeDropDown
        function AttributetypeDropDownValueChanged(app, event)
            update_FC(app)
            update_plot(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Feature Characterization')
            xlabel(app.UIAxes, 'profile length / mm')
            ylabel(app.UIAxes, 'profile height / µm')
            zlabel(app.UIAxes, 'Z')
            app.WatershedsegmentationPanel = uipanel(app.UIFigure);
            app.WatershedsegmentationPanel.Title = 'Watershed segmentation';
            app.CheckBox = uicheckbox(app.WatershedsegmentationPanel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '%';
            app.CheckBox.Value = true;
            app.PruningTypeDropDownLabel = uilabel(app.UIFigure);
            app.PruningTypeDropDownLabel.Text = 'Pruning Type';
            app.PruningTypeDropDown = uidropdown(app.UIFigure);
            app.PruningTypeDropDown.Items = {'None', 'Wolfprune', 'Width', 'VolS', 'DevLength'};
            app.PruningTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PruningTypeDropDownValueChanged, true);
            app.PruningTypeDropDown.Value = 'Wolfprune';
            app.FeaturetypeDropDownLabel = uilabel(app.UIFigure);
            app.FeaturetypeDropDownLabel.Text = 'Feature type';
            app.FeaturetypeDropDown = uidropdown(app.UIFigure);
            app.FeaturetypeDropDown.Items = {'H', 'D', 'P', 'V'};
            app.FeaturetypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FeaturetypeDropDownValueChanged, true);
            app.FeaturetypeDropDown.Value = 'D';
            app.ThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = 5;
            app.LoadprofileButton = uibutton(app.UIFigure, 'push');
            app.LoadprofileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadprofileButtonPushed, true);
            app.LoadprofileButton.Text = 'Load profile';
            app.FeatureparameterPanel = uipanel(app.UIFigure);
            app.FeatureparameterPanel.Title = 'Feature parameter';
            app.SignificantfeaturesDropDownLabel = uilabel(app.FeatureparameterPanel);
            app.SignificantfeaturesDropDownLabel.Text = 'Significant features';
            app.SignificantfeaturesDropDown = uidropdown(app.FeatureparameterPanel);
            app.SignificantfeaturesDropDown.Items = {'All', 'Open', 'Closed', 'Top', 'Bot'};
            app.SignificantfeaturesDropDown.ValueChangedFcn = createCallbackFcn(app, @SignificantfeaturesDropDownValueChanged, true);
            app.SignificantfeaturesDropDown.Value = 'All';
            app.AttributestatisticsDropDownLabel = uilabel(app.FeatureparameterPanel);
            app.AttributestatisticsDropDownLabel.Text = 'Attribute statistics';
            app.AttributestatisticsDropDown = uidropdown(app.FeatureparameterPanel);
            app.AttributestatisticsDropDown.Items = {'Mean', 'Max', 'Min', 'StdDev', 'Perc', 'Hist', 'Sum', 'Density'};
            app.AttributestatisticsDropDown.ValueChangedFcn = createCallbackFcn(app, @AttributestatisticsDropDownValueChanged, true);
            app.AttributestatisticsDropDown.Value = 'Mean';
            app.NIsigEditFieldLabel = uilabel(app.FeatureparameterPanel);
            app.NIsigEditFieldLabel.Text = 'NIsig';
            app.NIsigEditField = uieditfield(app.FeatureparameterPanel, 'numeric');
            app.NIsigEditField.ValueChangedFcn = createCallbackFcn(app, @NIsigEditFieldValueChanged, true);
            app.StatisticsvalueEditFieldLabel = uilabel(app.FeatureparameterPanel);
            app.StatisticsvalueEditFieldLabel.Text = 'Statistics value';
            app.StatisticsvalueEditField = uieditfield(app.FeatureparameterPanel, 'numeric');
            app.AttributetypeDropDownLabel = uilabel(app.FeatureparameterPanel);
            app.AttributetypeDropDownLabel.Text = 'Attribute type';
            app.AttributetypeDropDown = uidropdown(app.FeatureparameterPanel);
            app.AttributetypeDropDown.Items = {'HDh', 'HDw', 'HDv', 'HDl', 'PVh', 'Curvature', 'Count'};
            app.AttributetypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AttributetypeDropDownValueChanged, true);
            app.AttributetypeDropDown.Value = 'HDh';
            app.CheckBox_2 = uicheckbox(app.FeatureparameterPanel);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '%';
            app.ConventionISO21920Panel = uipanel(app.UIFigure);
            app.ConventionISO21920Panel.Title = 'Convention ISO-21920';
            app.FeatureCharacterizationEditField = uieditfield(app.ConventionISO21920Panel, 'text');
            app.FeatureCharacterizationEditField.ValueChangedFcn = createCallbackFcn(app, @FeatureCharacterizationEditFieldValueChanged, true);
            app.Label = uilabel(app.ConventionISO21920Panel);
            app.Label.Text = '0';
            app.Label_2 = uilabel(app.ConventionISO21920Panel);
            app.Label_2.Text = '=';
            app.mLabel = uilabel(app.ConventionISO21920Panel);
            app.mLabel.Text = 'µm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end